classdef MPA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        Menu_2                      matlab.ui.container.Menu
        ProfilePanel                matlab.ui.container.Panel
        SelectProfileDropDownLabel  matlab.ui.control.Label
        SelectProfileDropDown       matlab.ui.control.DropDown
        ProfileTable                matlab.ui.control.Table
        NewProfileButton            matlab.ui.control.Button
        ReportPanel                 matlab.ui.container.Panel
        AnalyzedDataLabel           matlab.ui.control.Label
        AnalyzedData                matlab.ui.control.ListBox
        LoadCheckBox                matlab.ui.control.CheckBox
        PerformanceCheckBox         matlab.ui.control.CheckBox
        IndividualCheckBox          matlab.ui.control.CheckBox
        TeamCheckBox                matlab.ui.control.CheckBox
        ReferenceCheckBox           matlab.ui.control.CheckBox
        CreateReportButton          matlab.ui.control.Button
        ExportButton                matlab.ui.control.Button
        AnalysisPanel               matlab.ui.container.Panel
        AvailableDataLabel          matlab.ui.control.Label
        AvailableData               matlab.ui.control.ListBox
        SelectDataButton            matlab.ui.control.Button
        LampLabel                   matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        AnalyzeDataButton           matlab.ui.control.Button
        ReferencePanel              matlab.ui.container.Panel
        SessionReferenceLabel       matlab.ui.control.Label
        SessionReference            matlab.ui.control.ListBox
        IndividualReferenceTable    matlab.ui.control.Table
        IndividualReferenceLabel    matlab.ui.control.Label
        ReferenceManagerButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        S % Settings: Profiles, VarNames, Source, Fields
        P % Profile: for Profile (ID) contains Rootfolder,Datafolder,SourceId, etc.
        
        
        ProfileId; 
        
        ProfileLabels; 
        DirNames;


    end
    
    properties (Access = public)
        SystemPath;
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            loadSettings()
            app.S=S;
            app.SystemPath=pwd;
            
            app.ProfileLabels=[string(app.SelectProfileDropDown.Items) app.S.Profile.Name];
            app.SelectProfileDropDown.Items = app.ProfileLabels;
            
            %%get initial states from app and add to profile callback
            %%'none'
            
        end

        % Value changed function: SelectProfileDropDown
        function SelectProfileDropDownValueChanged(app, event)
            value = app.SelectProfileDropDown.Value;
            app.ProfileId = find(strcmp(value,app.ProfileLabels),1)-1;
            
            if app.ProfileId==0 % check for 'none' and set state
                
                % don't show data
                
                app.ProfileTable.Data=[];
                app.ProfileTable.ColumnName={};
                app.AnalyzedData.Items={};
                app.AvailableData.Items = {};
                app.SessionReference.Items={};
                app.IndividualReferenceTable.Data=[];
                app.IndividualReferenceTable.ColumnName={};
                
                %                 % disable windows
                %                 app.ProfileTable.Enable = 'off';
                %                 app.AnalyzedData.Enable = 'off';
                %                 app.AvailableDataLabel.Enable = 'off';
                %                 app.SessionReference.Enable = 'off';
                %                 app.IndividualReferenceTable.Enable = 'off';
            else
                
                % update Profile
                
                app.P=profileset(app.S,app.ProfileId);

                % update analyzed data
                
                NewItems=getAnalyzedSessions(app.P.Rootfolder);
                app.AnalyzedData.Items=NewItems;
                
                % update data to analyze
                
                Files=getSessionsToAnalyze(app.P);
                app.AvailableData.Items=Files.Y;
                
                % update individual reference
                
                if isempty(app.P.Norm)
                    app.IndividualReferenceTable.Data=[];
                    app.IndividualReferenceTable.ColumnName={};
                else
                    NewIndividualReference=app.P.Norm;
                    app.IndividualReferenceTable.Data=NewIndividualReference;
                    app.IndividualReferenceTable.ColumnName = NewIndividualReference.Properties.VariableNames;
                end
                
                %update profile table
                
                rm={'Norm','Ref','Source'};
                NewProfileTable=rmfield(app.P,rm);
                NewProfileTable=struct2table(NewProfileTable);
                app.ProfileTable.Data=NewProfileTable;
                app.ProfileTable.ColumnName = NewProfileTable.Properties.VariableNames;
                
                % update Ref
                
                if ischar(app.P.Ref.Name)
                    RefList={app.P.Ref.Name};
                else
                    RefList={};
                end
                app.SessionReference.Items=RefList;
            end
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            try
                app.DirNames=uigetdir2;
                app.AvailableData.Items=app.DirNames;
            catch
            end
            
        end

        % Button pushed function: CreateReportButton
        function CreateReportButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.ProfilePanel = uipanel(app.UIFigure);
            app.SelectProfileDropDownLabel = uilabel(app.ProfilePanel);
            app.SelectProfileDropDownLabel.Text = 'Select Profile';
            app.SelectProfileDropDown = uidropdown(app.ProfilePanel);
            app.SelectProfileDropDown.Items = {'None'};
            app.SelectProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProfileDropDownValueChanged, true);
            app.SelectProfileDropDown.Value = 'None';
            app.ProfileTable = uitable(app.ProfilePanel);
            app.ProfileTable.ColumnName = {''};
            app.ProfileTable.RowName = {};
            app.NewProfileButton = uibutton(app.ProfilePanel, 'push');
            app.NewProfileButton.Text = 'New Profile';
            app.ReportPanel = uipanel(app.UIFigure);
            app.AnalyzedDataLabel = uilabel(app.ReportPanel);
            app.AnalyzedDataLabel.Text = 'Analyzed Data';
            app.AnalyzedData = uilistbox(app.ReportPanel);
            app.AnalyzedData.Items = {};
            app.AnalyzedData.Multiselect = 'on';
            app.AnalyzedData.Value = {};
            app.LoadCheckBox = uicheckbox(app.ReportPanel);
            app.LoadCheckBox.Text = 'Load';
            app.PerformanceCheckBox = uicheckbox(app.ReportPanel);
            app.PerformanceCheckBox.Text = 'Performance';
            app.IndividualCheckBox = uicheckbox(app.ReportPanel);
            app.IndividualCheckBox.Text = 'Individual';
            app.TeamCheckBox = uicheckbox(app.ReportPanel);
            app.TeamCheckBox.Text = 'Team';
            app.ReferenceCheckBox = uicheckbox(app.ReportPanel);
            app.ReferenceCheckBox.Text = 'Reference';
            app.CreateReportButton = uibutton(app.ReportPanel, 'push');
            app.CreateReportButton.ButtonPushedFcn = createCallbackFcn(app, @CreateReportButtonPushed, true);
            app.CreateReportButton.Text = 'Create Report';
            app.ExportButton = uibutton(app.ReportPanel, 'push');
            app.ExportButton.Text = 'Export';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AvailableDataLabel = uilabel(app.AnalysisPanel);
            app.AvailableDataLabel.Text = 'Available Data';
            app.AvailableData = uilistbox(app.AnalysisPanel);
            app.AvailableData.Items = {};
            app.AvailableData.Multiselect = 'on';
            app.AvailableData.Value = {};
            app.SelectDataButton = uibutton(app.AnalysisPanel, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.LampLabel = uilabel(app.AnalysisPanel);
            app.LampLabel.Text = '...';
            app.Lamp = uilamp(app.AnalysisPanel);
            app.AnalyzeDataButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalyzeDataButton.Text = 'Analyze Data';
            app.ReferencePanel = uipanel(app.UIFigure);
            app.SessionReferenceLabel = uilabel(app.ReferencePanel);
            app.SessionReferenceLabel.Text = 'Session Reference';
            app.SessionReference = uilistbox(app.ReferencePanel);
            app.SessionReference.Items = {};
            app.SessionReference.Multiselect = 'on';
            app.SessionReference.Value = {};
            app.IndividualReferenceTable = uitable(app.ReferencePanel);
            app.IndividualReferenceTable.ColumnName = {'LfdNr'; 'SpielerNr'; 'Name'; 'Vorname'; 'Alter'; 'Größe'; 'Gewicht'; 'MP4'; 'EC0'};
            app.IndividualReferenceTable.RowName = {};
            app.IndividualReferenceLabel = uilabel(app.ReferencePanel);
            app.IndividualReferenceLabel.Text = 'Individual Reference';
            app.ReferenceManagerButton = uibutton(app.ReferencePanel, 'push');
            app.ReferenceManagerButton.Text = 'Reference Manager';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MPA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end